classdef app_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        AddscanButton               matlab.ui.control.Button
        LoadscansButton             matlab.ui.control.Button
        MapnameEditFieldLabel       matlab.ui.control.Label
        MapnameEditField            matlab.ui.control.EditField
        UndoscanButton              matlab.ui.control.Button
        SavescansButton             matlab.ui.control.Button
        ConnectcamButton            matlab.ui.control.Button
        CreatemapButton             matlab.ui.control.Button
        GetpositionButton           matlab.ui.control.Button
        ZUMOIPEditFieldLabel        matlab.ui.control.Label
        ZUMOIPEditField             matlab.ui.control.EditField
        CamIPEditFieldLabel         matlab.ui.control.Label
        CamIPEditField              matlab.ui.control.EditField
        SaveIPconfigButton          matlab.ui.control.Button
        LoadIPconfigButton          matlab.ui.control.Button
        IPConfignameEditFieldLabel  matlab.ui.control.Label
        IPConfignameEditField       matlab.ui.control.EditField
        LocksteeringSwitchLabel     matlab.ui.control.Label
        LocksteeringSwitch          matlab.ui.control.Switch
    end

    % Properties used to control robot and perform SLAM
    properties (Access = private)
        robot = ZUMO('');
        scan_man = scan_manager(20, 5, 1000, 40);
        cam
        cam_ip
        ready_map
        robot_pos_x = 0
        robot_pos_y = 0
        map_created = 0
        lock_steering = false
    end
    
    methods (Access = private)
        
        function Idle_mode(app)
            webread(strcat(app.robot.ip_address, '/stop'));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddscanButton
        function AddscanButtonPushed(app, event)
            app.robot.get_scan();
            app.scan_man.add_scan(app.robot.raw_scan);
            cla(app.UIAxes);
            h = show(app.scan_man.slam_alg);
            copyobj(h.Children, app.UIAxes);  % Copy all of the axis' children to your app axis
            delete(h.Parent) % get rid of the figure created by worldmap()
            axis(app.UIAxes, 'equal');
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if ~app.lock_steering
                switch key
                    case 'w'
                           webread(strcat(app.robot.ip_address, '/forward'));
                    case 'a'
                        webread(strcat(app.robot.ip_address, '/right'));
                    case 'd'
                        webread(strcat(app.robot.ip_address, '/left'));
                    case 's'
                        webread(strcat(app.robot.ip_address, '/back'));
                    case 'space'
                        webread(strcat(app.robot.ip_address, '/stop'));
                    case 'uparrow'
                        webread(strcat(app.robot.ip_address, '/speed_up'));
                    case 'downarrow'
                        webread(strcat(app.robot.ip_address, '/speed_down'));
                end
            end
        end

        % Button pushed function: UndoscanButton
        function UndoscanButtonPushed(app, event)
            app.scan_man.pop_scan();
            cla(app.UIAxes);
            h = show(app.scan_man.slam_alg);
            copyobj(h.Children, app.UIAxes);  % Copy all of the axis' children to your app axis
            delete(h.Parent) % get rid of the figure created by worldmap()
            axis(app.UIAxes, 'equal');
        end

        % Button pushed function: SavescansButton
        function SavescansButtonPushed(app, event)
            fid = fopen(strcat(app.MapnameEditField.Value, '.txt'), 'wt');
            fprintf(fid, strjoin(app.scan_man.scans, '\n'));
            fclose(fid);
        end

        % Button pushed function: LoadscansButton
        function LoadscansButtonPushed(app, event)
            map = fileread(strcat(app.MapnameEditField.Value, '.txt'));
            map = strsplit(map, "\n");
            app.scan_man.scans = map;
            app.scan_man.remake_map();
            cla(app.UIAxes);
            h = show(app.scan_man.slam_alg);
            copyobj(h.Children, app.UIAxes);  % Copy all of the axis' children to your app axis
            delete(h.Parent) % get rid of the figure created by worldmap()
            axis(app.UIAxes, 'equal');
        end

        % Button pushed function: ConnectcamButton
        function ConnectcamButtonPushed(app, event)
            %app.cam = ipcam("http://10.204.21.196:4747/video");
            app.cam = ipcam(app.cam_ip);
            preview(app.cam);
        end

        % Button pushed function: CreatemapButton
        function CreatemapButtonPushed(app, event)
            [scans, optimizedPoses]  = scansAndPoses(app.scan_man.slam_alg); 
            app.ready_map = buildMap(scans, optimizedPoses, 30, 3);
            app.scan_man.create_super_scan();
            
            plot(app.scan_man.super_scan);
            hold on;
            plot(app.scan_man.x_points, app.scan_man.y_points, 'og');
            
            cla(app.UIAxes);
            h = show(app.ready_map);
            copyobj(h, app.UIAxes);  % Copy all of the axis' children to your app axis
            hold(app.UIAxes);
            scatter(app.UIAxes, app.robot_pos_x, app.robot_pos_y, [], 'red', 'filled');
            %close all;
            axis(app.UIAxes, 'equal');
            app.map_created = 1;
        end

        % Button pushed function: GetpositionButton
        function GetpositionButtonPushed(app, event)
            if app.map_created == 1
                close all;
                app.robot.get_scan();
                pose = app.scan_man.get_pos(app.robot.raw_scan);
                pose
                app.robot_pos_x = pose(1);
                app.robot_pos_y = pose(2);
                
                cla(app.UIAxes);
                h = show(app.ready_map);
                %copyobj(h, app.UIAxes);  % Copy all of the axis' children to your app axis
                hold on;
                scatter(app.robot_pos_x, app.robot_pos_y, [], 'red', 'filled');
                %scatter(app.UIAxes, 0, 0, [], 'green', 'filled');
                
%                 close all;
%                 plot(app.scan_man.super_scan);
%                 hold on;
%                 plot(transformScan(app.scan_man.aux, pose));
%                 axis(app.UIAxes, 'equal');
            end
        end

        % Value changed function: ZUMOIPEditField
        function ZUMOIPEditFieldValueChanged(app, event)
            value = app.ZUMOIPEditField.Value;
            app.robot.ip_address = value;
        end

        % Value changed function: CamIPEditField
        function CamIPEditFieldValueChanged(app, event)
            value = app.CamIPEditField.Value;
            app.cam_ip = value;
        end

        % Button pushed function: SaveIPconfigButton
        function SaveIPconfigButtonPushed(app, event)
            file = fopen(strcat(app.IPConfignameEditField.Value, ".txt"),'wt');
            fprintf(file, strcat(app.ZUMOIPEditField.Value, "\n", app.CamIPEditField.Value));
            fclose(file);
        end

        % Button pushed function: LoadIPconfigButton
        function LoadIPconfigButtonPushed(app, event)
            IPs = fileread(strcat(app.IPConfignameEditField.Value, ".txt"));
            IPs = strsplit(IPs, "\n");
            app.ZUMOIPEditField.Value = string(IPs(1));
            app.CamIPEditField.Value = string(IPs(2));
            app.robot.ip_address = string(IPs(1));
            app.cam_ip = string(IPs(2));
            
        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)
            if ~app.lock_steering
                key = event.Key;                         
                if key == 'w' || key == 's' || key == 'a' || key == 'd'
                    webread(strcat(app.robot.ip_address, '/stop'));
                end
            end
        end

        % Value changed function: LocksteeringSwitch
        function LocksteeringSwitchValueChanged(app, event)
            value = app.LocksteeringSwitch.Value;
            if strcmp('On', value)
                app.lock_steering = true;
            else
                app.lock_steering = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Map')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.AddscanButton = uibutton(app.UIFigure, 'push');
            app.AddscanButton.ButtonPushedFcn = createCallbackFcn(app, @AddscanButtonPushed, true);
            app.AddscanButton.Text = 'Add scan';
            app.LoadscansButton = uibutton(app.UIFigure, 'push');
            app.LoadscansButton.ButtonPushedFcn = createCallbackFcn(app, @LoadscansButtonPushed, true);
            app.LoadscansButton.Text = 'Load scans';
            app.MapnameEditFieldLabel = uilabel(app.UIFigure);
            app.MapnameEditFieldLabel.Text = 'Map name';
            app.MapnameEditField = uieditfield(app.UIFigure, 'text');
            app.UndoscanButton = uibutton(app.UIFigure, 'push');
            app.UndoscanButton.ButtonPushedFcn = createCallbackFcn(app, @UndoscanButtonPushed, true);
            app.UndoscanButton.Text = 'Undo scan';
            app.SavescansButton = uibutton(app.UIFigure, 'push');
            app.SavescansButton.ButtonPushedFcn = createCallbackFcn(app, @SavescansButtonPushed, true);
            app.SavescansButton.Text = 'Save scans';
            app.ConnectcamButton = uibutton(app.UIFigure, 'push');
            app.ConnectcamButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectcamButtonPushed, true);
            app.ConnectcamButton.Text = 'Connect cam';
            app.CreatemapButton = uibutton(app.UIFigure, 'push');
            app.CreatemapButton.ButtonPushedFcn = createCallbackFcn(app, @CreatemapButtonPushed, true);
            app.CreatemapButton.Text = 'Create map';
            app.GetpositionButton = uibutton(app.UIFigure, 'push');
            app.GetpositionButton.ButtonPushedFcn = createCallbackFcn(app, @GetpositionButtonPushed, true);
            app.GetpositionButton.Text = 'Get position';
            app.ZUMOIPEditFieldLabel = uilabel(app.UIFigure);
            app.ZUMOIPEditFieldLabel.Text = 'ZUMO IP';
            app.ZUMOIPEditField = uieditfield(app.UIFigure, 'text');
            app.ZUMOIPEditField.ValueChangedFcn = createCallbackFcn(app, @ZUMOIPEditFieldValueChanged, true);
            app.CamIPEditFieldLabel = uilabel(app.UIFigure);
            app.CamIPEditFieldLabel.Text = 'Cam IP';
            app.CamIPEditField = uieditfield(app.UIFigure, 'text');
            app.CamIPEditField.ValueChangedFcn = createCallbackFcn(app, @CamIPEditFieldValueChanged, true);
            app.SaveIPconfigButton = uibutton(app.UIFigure, 'push');
            app.SaveIPconfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveIPconfigButtonPushed, true);
            app.SaveIPconfigButton.Text = 'Save IP config';
            app.LoadIPconfigButton = uibutton(app.UIFigure, 'push');
            app.LoadIPconfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadIPconfigButtonPushed, true);
            app.LoadIPconfigButton.Text = 'Load IP config';
            app.IPConfignameEditFieldLabel = uilabel(app.UIFigure);
            app.IPConfignameEditFieldLabel.Text = 'IP Config name';
            app.IPConfignameEditField = uieditfield(app.UIFigure, 'text');
            app.LocksteeringSwitchLabel = uilabel(app.UIFigure);
            app.LocksteeringSwitchLabel.Text = 'Lock steering';
            app.LocksteeringSwitch = uiswitch(app.UIFigure, 'slider');
            app.LocksteeringSwitch.ValueChangedFcn = createCallbackFcn(app, @LocksteeringSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end